classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        UploadImageButton      matlab.ui.control.Button
        MusicScoreReaderLabel  matlab.ui.control.Label
        UIAxes_2               matlab.ui.control.UIAxes
        GenerateAudioButton    matlab.ui.control.Button
    end

    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            global image2
            [filename, pathname] = uigetfile({'*.jpg';'*.jpeg';'*.bmp';'*.png';'*.tif';'*.tiff'}, 'Open File');
            fullpath = strcat(pathname, filename);
            image2 = rgb2gray(imread(fullpath));
            imshow(image2, 'parent', app.UIAxes)
        end

        % Button pushed function: GenerateAudioButton
        function GenerateAudioButtonPushed(app, event)
            global image2
            % Binarizing Image
            binarized_image = ~(imbinarize(image2));
            recognizedScore = ProcessStaves(binarized_image);
            GenerateAudio(recognizedScore);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.MusicScoreReaderLabel = uilabel(app.UIFigure);
            app.MusicScoreReaderLabel.Text = 'Music Score Reader';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.GenerateAudioButton = uibutton(app.UIFigure, 'push');
            app.GenerateAudioButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAudioButtonPushed, true);
            app.GenerateAudioButton.Text = 'Generate Audio';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end